classdef gravityWaveAnalyisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GravityWaveAnalysisGUIUIFigure  matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        AnalysisaltituderangeMenu       matlab.ui.container.Menu
        SavedirectoryMenu               matlab.ui.container.Menu
        AboutthisappMenu                matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        LoadfileButton                  matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        TropopauseEditFieldLabel        matlab.ui.control.Label
        TropopauseEditField             matlab.ui.control.NumericEditField
        PBLEditFieldLabel               matlab.ui.control.Label
        PBLEditField                    matlab.ui.control.NumericEditField
        WindshearEditFieldLabel         matlab.ui.control.Label
        WindshearEditField              matlab.ui.control.EditField
        LowleveljetsEditFieldLabel      matlab.ui.control.Label
        LowleveljetsEditField           matlab.ui.control.EditField
        SavedataButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentFile % File currently being analyzed.
        lowerCutOffAltitude = 12000;
        upperCutOffAltitude = 25000;
        saveDirectory = 'gravityWaveData';
        tropopause = [];
        lowLevelJets = [];
        windShearLevels = [];
        planetaryBoundaryLayer = [];
        latitudeArray = [];
        longitudeArray = [];
        data = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('wave_matlab/');
            warning('off', 'MATLAB:polyfit:RepeatedPointsOrRescale');
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.txt');
            % clear axes
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            if baseName == 0
                return
            end
            app.currentFile = fullfile(folder, baseName);
            showPowerSurfaces = false;
            save = false;
            [~, titleName, ~] = fileparts(app.currentFile);
            set(app.UIAxes, 'YScale', 'log')
            titleString = sprintf("%s", titleName);
            title(app.UIAxes, titleString, 'Interpreter', 'none');
            [app.latitudeArray, app.longitudeArray, altitude, ...
                app.data, waveletTransform, clippedAlt, ...
                gWaveLocations] = doAnalysis(app.currentFile, save, app.saveDirectory, showPowerSurfaces, app.lowerCutOffAltitude, app.upperCutOffAltitude);
            if isempty(app.data)
                title(app.UIAxes, 'No gravity waves detected');
                title(app.UIAxes_2, 'No gravity waves detected');
                msgbox(sprintf("No gravity waves detected for %s", baseName));
                return
            end
            placeholder = zeros(size(altitude), 'like', altitude);
            plot(app.UIAxes_2, placeholder, altitude/1000, 'k'); % plot altitude in km.
            hold(app.UIAxes_2, 'on');
            % The magnitudes of the red lines are the axial ratios of the
            % gravity waves - axial ratio = intrinsic frequency / coriolis
            % frequency.
            % The for loop below just plots the red lines in the direction of
            % propagation of the gravity wave
            offset = 0;
            for q=1:size(app.data.alt_of_detection_km)
                x1 = offset;
                magnitude = app.data.axial_ratio(q);
                angle = app.data.propagation_dir(q);
                x2 = offset + magnitude*cosd(angle);
                y1 = app.data.alt_of_detection_km(q);
                y2 = app.data.alt_of_detection_km(q) + magnitude*sind(angle);
                plot(app.UIAxes_2, [x1 x2], [y1 y2], 'r');
            end
            xlim(app.UIAxes_2, [-5 5]);
            title(app.UIAxes_2, "Gravity wave altitude of detection and propagation direction", 'FontSize', 8);
            ylabel(app.UIAxes_2, 'Altitude (km)');
            set(app.UIAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
            set(app.UIAxes, 'YTickMode', 'auto', 'YTickLabelMode', 'auto');
            %set(app.UIAxes_2, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
            set(app.UIAxes_2, 'YTickMode', 'auto', 'YTickLabelMode', 'auto'); 
            contourf(app.UIAxes, clippedAlt, waveletTransform.fourierWavelength, waveletTransform.powerSurface);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, clippedAlt, waveletTransform.coi, 'k');
            s = scatter(app.UIAxes, clippedAlt(gWaveLocations(:, 1)), waveletTransform.fourierWavelength(gWaveLocations(:, 2)), 'ro');
            legend(app.UIAxes, s, 'gravity wave');
            ylim(app.UIAxes, [waveletTransform.s0 Inf]);
            ylabel(app.UIAxes, 'Wavelength (m)');
            xlabel(app.UIAxes, 'Altitude (m)');
        end

        % Menu selected function: AnalysisaltituderangeMenu
        function AnalysisaltituderangeMenuSelected(app, event)
           x = inputdlg({'Lower altitude bound (km)', 'Upper altitude bound (km)'}, 'Altitude range selection', [1 25; 1, 25], {'12', '25'});
           app.lowerCutOffAltitude = str2double(x{1})*1000;
           app.upperCutOffAltitude = str2double(x{2})*1000;
        end

        % Menu selected function: SavedirectoryMenu
        function SavedirectoryMenuSelected(app, event)
           prompt = {'Enter a directory to save data in'};
           dlgtitle = 'Directory';
           definput = {'gravityWaveData'};
           opts.Interpreter = 'tex';
           app.saveDirectory = inputdlg(prompt,dlgtitle,[1 40],definput,opts);
           app.saveDirectory = app.saveDirectory{1};
        end

        % Value changed function: TropopauseEditField
        function TropopauseEditFieldValueChanged(app, event)
        try    
            app.tropopause = app.TropopauseEditField.Value;
            xs = xlim(app.UIAxes_2);
            ys = zeros(size(xs), 'like', xs) + app.tropopause;
            tropoPauseLine = plot(app.UIAxes_2, xs, ys, 'c');
            legend(app.UIAxes_2, tropoPauseLine, 'tropopause')
        catch
            helpdlg("Tropopause edit field only accepts altitude of tropopause in km.")
        end
        end

        % Value changed function: PBLEditField
        function PBLEditFieldValueChanged(app, event)
        try     
            app.planetaryBoundaryLayer = app.PBLEditField.Value;
            xs = xlim(app.UIAxes_2);
            ys = zeros(size(xs), 'like', xs) + app.planetaryBoundaryLayer;
            pblLine = plot(app.UIAxes_2, xs, ys, 'm');
            legend(app.UIAxes_2, pblLine, 'pbl')
        catch
            helpdlg("PBL edit field only accepts altitude of tropopause in km.")
        end
        end

        % Value changed function: WindshearEditField
        function WindshearEditFieldValueChanged(app, event)
        try    
            value = app.WindshearEditField.Value;
            s = split(value, ',');
            app.windShearLevels = cellfun(@str2num,s(1:end));
            app.windShearLevels = app.windShearLevels;
            xs = xlim(app.UIAxes_2);
            for i=1:size(app.windShearLevels)
                ys = zeros(size(xs), 'like', xs) + app.windShearLevels(i);
                windShearLine = plot(app.UIAxes_2, xs, ys, 'g');
                legend(app.UIAxes_2, windShearLine, 'wind shear')
            end
        catch
           helpdlg("Wind shear edit field only accepts a comma-delimited list of wind shear levels in km.")
        end
        end

        % Value changed function: LowleveljetsEditField
        function LowleveljetsEditFieldValueChanged(app, event)
            try
            value = app.LowleveljetsEditField.Value;
            s = split(value, ',');
            app.lowLevelJets = cellfun(@str2num,s(1:end));
            app.lowLevelJets = app.lowLevelJets;
            xs = xlim(app.UIAxes_2);
            for i=1:size(app.lowLevelJets)
                ys = zeros(size(xs), 'like', xs) + app.lowLevelJets(i);
                lowLevelJet = plot(app.UIAxes_2, xs, ys, 'k');
                legend(app.UIAxes_2, lowLevelJet, 'low level jet')
            end
            catch
                helpdlg("Low level jets edit field only accepts a comma-delimited list of jet levels in km.")
            end
        end

        % Menu selected function: AboutthisappMenu
        function AboutthisappMenuSelected(app, event)
        msgstring = fileread('readmeGUI.txt');
        msgbox(msgstring, 'About this app','help');
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            if isempty(app.tropopause)
                app.tropopause = NaN;
            end
            if isempty(app.planetaryBoundaryLayer)
                app.planetaryBoundaryLayer = NaN;
            end
            if isempty(app.windShearLevels)
               app.windShearLevels = NaN;
            end
            if isempty(app.lowLevelJets)
               app.lowLevelJets = NaN;
            end
            maxLength = max(size(app.windShearLevels), size(app.lowLevelJets));
            maxLength = maxLength(1, 1);
            if maxLength == 0
                maxLength = 1;
            end
            llj = zeros(1, maxLength);
            llj(1:size(app.lowLevelJets)) = app.lowLevelJets*1000;
            wsl = zeros(1, maxLength);
            wsl(1:size(app.windShearLevels)) = app.windShearLevels*1000;
            tropo = zeros(1, maxLength);
            tropo(1) = app.tropopause*1000;
            pbl = zeros(1, maxLength);
            pbl(1) = app.planetaryBoundaryLayer*1000;
            header = {'wind_shear_levels', 'low_level_jets', 'tropopause', 'planetary_boundary_layer'};
            tbl = [wsl; llj; tropo; pbl]';
            dataBlock = array2table(tbl, 'VariableNames', header);
            [~, saveFileName, ~] = fileparts(app.currentFile);
            saveFileNameSignificantLevels = strcat(saveFileName, '_significant_levels.csv');
            saveFileNameGravityWaveData = strcat(saveFileName, '_gravity_wave_parameters.csv');
            saveFileNameSignificantLevels = fullfile(app.saveDirectory, saveFileNameSignificantLevels);
            saveFileNameGravityWaveData = fullfile(app.saveDirectory, saveFileNameGravityWaveData);
            
            if isfile(saveFileNameSignificantLevels)
                s = sprintf("File %s already exists. Would you like to overwrite the data? If no, levels are appended to the file.", saveFileNameSignificantLevels);
                answer = questdlg(s, 'Overwrite or append', 'Yes', 'No', 'Yes');
                switch answer
                    case 'Yes'
                       writetable(dataBlock, saveFileNameSignificantLevels);
                    case 'No'
                        dlmwrite(saveFileNameSignificantLevels, tbl, 'delimiter', ',', '-append');
                end
            else
                writetable(dataBlock, saveFileNameSignificantLevels);
            end
            writetable(app.data, saveFileNameGravityWaveData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GravityWaveAnalysisGUIUIFigure = uifigure('Visible', 'off');
            app.GravityWaveAnalysisGUIUIFigure.Name = 'Gravity Wave Analysis GUI';
            app.Menu = uimenu(app.GravityWaveAnalysisGUIUIFigure);
            app.Menu.Text = 'Menu';
            app.AnalysisaltituderangeMenu = uimenu(app.Menu);
            app.AnalysisaltituderangeMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisaltituderangeMenuSelected, true);
            app.AnalysisaltituderangeMenu.Text = 'Analysis altitude range ';
            app.SavedirectoryMenu = uimenu(app.Menu);
            app.SavedirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedirectoryMenuSelected, true);
            app.SavedirectoryMenu.Text = 'Save directory';
            app.AboutthisappMenu = uimenu(app.Menu);
            app.AboutthisappMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutthisappMenuSelected, true);
            app.AboutthisappMenu.Text = 'About this app';
            app.UIAxes = uiaxes(app.GravityWaveAnalysisGUIUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadfileButton = uibutton(app.GravityWaveAnalysisGUIUIFigure, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.UIAxes_2 = uiaxes(app.GravityWaveAnalysisGUIUIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.TropopauseEditFieldLabel = uilabel(app.GravityWaveAnalysisGUIUIFigure);
            app.TropopauseEditFieldLabel.Text = 'Tropopause';
            app.TropopauseEditField = uieditfield(app.GravityWaveAnalysisGUIUIFigure, 'numeric');
            app.TropopauseEditField.ValueChangedFcn = createCallbackFcn(app, @TropopauseEditFieldValueChanged, true);
            app.TropopauseEditField.Tooltip = {'Enter tropopause height in km.'};
            app.PBLEditFieldLabel = uilabel(app.GravityWaveAnalysisGUIUIFigure);
            app.PBLEditFieldLabel.Text = 'PBL';
            app.PBLEditField = uieditfield(app.GravityWaveAnalysisGUIUIFigure, 'numeric');
            app.PBLEditField.ValueChangedFcn = createCallbackFcn(app, @PBLEditFieldValueChanged, true);
            app.PBLEditField.Tooltip = {'PBL height in km'};
            app.WindshearEditFieldLabel = uilabel(app.GravityWaveAnalysisGUIUIFigure);
            app.WindshearEditFieldLabel.Text = 'Wind shear';
            app.WindshearEditField = uieditfield(app.GravityWaveAnalysisGUIUIFigure, 'text');
            app.WindshearEditField.ValueChangedFcn = createCallbackFcn(app, @WindshearEditFieldValueChanged, true);
            app.WindshearEditField.Tooltip = {'Wind shear (km). Enter multiple values as comma-delimited list'};
            app.LowleveljetsEditFieldLabel = uilabel(app.GravityWaveAnalysisGUIUIFigure);
            app.LowleveljetsEditFieldLabel.Text = 'Low level jets';
            app.LowleveljetsEditField = uieditfield(app.GravityWaveAnalysisGUIUIFigure, 'text');
            app.LowleveljetsEditField.ValueChangedFcn = createCallbackFcn(app, @LowleveljetsEditFieldValueChanged, true);
            app.LowleveljetsEditField.Tooltip = {'Enter multiple values as a comma delimited list'};
            app.SavedataButton = uibutton(app.GravityWaveAnalysisGUIUIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            % Show the figure after all components are created
            app.GravityWaveAnalysisGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gravityWaveAnalyisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GravityWaveAnalysisGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GravityWaveAnalysisGUIUIFigure)
        end
    end
end