classdef gravity_wave_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        PreviousButton                 matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        NextButton                     matlab.ui.control.Button
        FitellipseButton               matlab.ui.control.Button
        NextdatafileButton             matlab.ui.control.Button
        SavefitButton                  matlab.ui.control.Button
        OpenfilebrowserButton          matlab.ui.control.Button
        DoneTextAreaLabel              matlab.ui.control.Label
        DoneTextArea                   matlab.ui.control.TextArea
        InvertwindowedtransformButton  matlab.ui.control.Button
        OnequartermaxwindowButton      matlab.ui.control.Button
        PreviousdatafileButton         matlab.ui.control.Button
        CalculateStokesParametersButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        data_files
        file_counter = 1;
        selpath
        rows
        cols
        local_maxima_counter = 0;
        waveletTransform;
        windowedWaveletTransform;
        alt
        userDrawnRectangle = false;
        current_max_row
        vInverted
        uInverted = 0;
        vHilbertTransformed
        current_max_col
    end
    
    methods (Access = public)
        % TODO: Add a callback on both axes that handles the user drawing a
        % rectangle. The button ``invert wavelet transform'' should get
        % the bounding box around the identified local maxima.
        function display_text(app, string)
            app.DoneTextArea.Value = string;
        end
        
        function display_windowed_power_surface(app, color)
           ofs = 99;
           if(app.local_maxima_counter > size(app.rows, 2))
               display_text(app, "Out of local maxima. Move to next file.")
               app.local_maxima_counter = 1;
           end
           if (app.local_maxima_counter < 1)
               app.local_maxima_counter = 1;
           end
           n_rows = size(app.waveletTransform.power_surface, 1);
           n_cols = size(app.waveletTransform.power_surface, 2);
           app.current_max_row = app.rows(app.local_maxima_counter);
           app.current_max_col = app.cols(app.local_maxima_counter);
           first_row = app.current_max_row - ofs;
           last_row = app.current_max_row + ofs;
           first_col = app.current_max_col - ofs;
           last_col = app.current_max_col + ofs;
           if (app.current_max_row-ofs) < 1
               first_row = 1;
           end
           if (app.current_max_row + ofs) > n_rows
               last_row = n_rows;
           end
           if (app.current_max_col + ofs) > n_cols
               last_col = n_cols;
           end
           if (app.current_max_col - ofs) < 1
               first_col = 1;
           end
           app.windowedWaveletTransform = WindowedWaveletTransform(first_row, last_row, first_col, last_col);
           contourf(app.UIAxes_2, app.waveletTransform.power_surface(first_row:last_row, first_col:last_col));
           colorbar(app.UIAxes_2)
           hold(app.UIAxes_2, 'on');
           center_row = 99;
           center_col = 99;
           if first_row == 1
               center_row = app.current_max_row; 
           end
           if first_col == 1
               center_col = app.current_max_col;
           end
           scatter(app.UIAxes_2, center_col+1, center_row+1, [150], 'rx');
           hold(app.UIAxes_2, 'off');
           hold(app.UIAxes, 'on')
           scatter(app.UIAxes, app.alt(app.current_max_col), app.waveletTransform.fourierPeriod(app.current_max_row),  color);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenfilebrowserButton
        function OpenfilebrowserButtonPushed(app, event)
            app.file_counter = 1;
            app.selpath = uigetdir();
            if app.selpath == 0
                return
            end
            txtpattern = fullfile(app.selpath, '*.txt');
            app.data_files = dir(txtpattern);
        end

        % Button pushed function: NextdatafileButton
        function NextdatafileButtonPushed(app, event)
            if isempty(app.data_files)
               return
            end
            if size(app.data_files) < app.file_counter
                app.file_counter = 1;
            end
            currentFile = app.data_files(app.file_counter);
            currentFile = currentFile.name;
            disp(currentFile);
            file = fullfile(app.selpath, currentFile);3
            display_text(app, "Loading data...");
            data = readRadioSondeData(file);
            cla(app.UIAxes, "reset");
            cla(app.UIAxes_2, "reset");
            display_text(app, int2str(app.file_counter));
            app.file_counter = app.file_counter + 1;
            if isempty(data)
                str = sprintf("File %s appears to be empty.", currentFile);
                display_text(app, str);
                return
            end
            
            [maxAlt, mai] = max(data.Alt);
            if maxAlt < 13000
                str = sprintf("Flight %s only reached %d km", currentFile, round(maxAlt / 1000));
                display_text(app, str);
                return
            end            
            ws = data.Ws(1:mai);
            wd = data.Wd(1:mai);
            temperature = data.T(1:mai) + 273.15;
            app.alt = data.Alt(1:mai);
            uWindComponent_i = ws.*cosd(wd);
            vWindComponent = ws.*sind(wd);
            time = data.Time(1:mai);
            pU = polyfit(time, uWindComponent_i, 3);
            pV = polyfit(time, vWindComponent, 3);
            pT = polyfit(time, temperature, 3);
            uMeanFlow = polyval(pU, time);
            vMeanFlow = polyval(pV, time);
            tempMeanFlow = polyval(pT, time);
            uWindComponent = uWindComponent_i - uMeanFlow;
            vWindComponent = vWindComponent - vMeanFlow;
            tempPerturbations = temperature - tempMeanFlow;
            altitudeSamplingFrequency = 50; % 100m altitudeSamplingFrequency
            [uWindComponent, ~] = averageToAltitudeResolution(uWindComponent, app.alt, altitudeSamplingFrequency);
            [vWindComponent, ~] = averageToAltitudeResolution(vWindComponent, app.alt, altitudeSamplingFrequency);
            [~, app.alt] = averageToAltitudeResolution(tempPerturbations, app.alt, altitudeSamplingFrequency);
            dt = altitudeSamplingFrequency;
            app.waveletTransform = WaveletTransform(uWindComponent, vWindComponent, dt);
            [uu, ~] = app.waveletTransform.invertWaveletTransform();
            plot(app.UIAxes_2, uWindComponent, app.alt, 'r');
            hold(app.UIAxes_2, "on");
            plot(app.UIAxes_2, uu, app.alt, 'k');
            hold(app.UIAxes_2, "off");
            display_text(app, "Done calculating transform");
            title(app.UIAxes, currentFile);
            set(app.UIAxes, 'Yscale', 'log');
            contourf(app.UIAxes, app.alt, app.waveletTransform.fourierPeriod, app.waveletTransform.power_surface);
            colorbar(app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.alt, app.waveletTransform.coi, 'k')
            temp = app.waveletTransform.power_surface;
            temp(temp < 0.01) = 0; % noise thresholding. This corresponds to a perturbation amplitude of 0.1 m/s.
            points = imregionalmax(temp, 8);
            [app.rows, app.cols] = find(points);
            [app.rows, app.cols] = filterWavePacketCandidates(app.waveletTransform, app.rows, app.cols);
            s = sprintf("Number of acceptable wavelet candidates: %d\n", size(app.rows, 2));
            display_text(app, s);
            scatter(app.UIAxes, app.alt(app.cols), app.waveletTransform.fourierPeriod(app.rows), [], 'ro');
            ylim(app.UIAxes, [app.waveletTransform.s0, inf]);

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.local_maxima_counter = app.local_maxima_counter + 1;   
            display_windowed_power_surface(app, 'gx');
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
           app.local_maxima_counter = app.local_maxima_counter - 1;
           display_windowed_power_surface(app, 'kx');
        end

        % Button pushed function: InvertwindowedtransformButton
        function InvertwindowedtransformButtonPushed(app, event)
            if isempty(app.windowedWaveletTransform)
                display_text(app, "no windowed transform selected");
                return;
            end
            if isempty(app.waveletTransform)
                return;
            end
            [app.uInverted, app.vInverted, app.vHilbertTransformed] = app.waveletTransform.invertWindowedTransform(app.windowedWaveletTransform);
            hold(app.UIAxes_2, 'off');
            plot(app.UIAxes_2, app.uInverted, app.vInverted);
        end

        % Button pushed function: OnequartermaxwindowButton
        function OnequartermaxwindowButtonPushed(app, event)
            if (app.userDrawnRectangle)
                % Get max
                five = 5;
            else
                [row_index_1, row_index_2, col_index_1, col_index_2] = app.waveletTransform.clipWindowedTransformToValue(app.windowedWaveletTransform, app.current_max_row, app.current_max_col);
            end
            try
            contourf(app.UIAxes_2, app.waveletTransform.power_surface(row_index_1:row_index_2, col_index_1:col_index_2));
            colorbar(app.UIAxes_2)
            app.windowedWaveletTransform = WindowedWaveletTransform(row_index_1, row_index_2, col_index_1, col_index_2);
            catch
                fprintf("%d, %d, %d, %d", row_index_1, row_index_2, col_index_1, col_index_2);
            end
            %hold(app.UIAxes_2, 'on');
            
            
        end

        % Button pushed function: CalculateStokesParametersButton
        function CalculateStokesParametersButtonPushed(app, event)
            [theta, axialRatio, degreeOfPolarization, Q] = estimateParametersFromWavePacket(app.uInverted, app.vInverted, app.vHilbertTransformed);
            title(app.UIAxes_2, sprintf("%f", Q));
            fprintf("%f, %f, %f, %f\n", theta, axialRatio, degreeOfPolarization, 1/axialRatio);
        
        end

        % Button pushed function: PreviousdatafileButton
        function PreviousdatafileButtonPushed(app, event)
            return;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wavelet Power Surface')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FitellipseButton = uibutton(app.UIFigure, 'push');
            app.FitellipseButton.Text = 'Fit ellipse';
            app.NextdatafileButton = uibutton(app.UIFigure, 'push');
            app.NextdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @NextdatafileButtonPushed, true);
            app.NextdatafileButton.Text = 'Next data file';
            app.SavefitButton = uibutton(app.UIFigure, 'push');
            app.SavefitButton.Text = 'Save fit';
            app.OpenfilebrowserButton = uibutton(app.UIFigure, 'push');
            app.OpenfilebrowserButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfilebrowserButtonPushed, true);
            app.OpenfilebrowserButton.Text = 'Open file browser';
            app.DoneTextAreaLabel = uilabel(app.UIFigure);
            app.DoneTextAreaLabel.Text = 'Done';
            app.DoneTextArea = uitextarea(app.UIFigure);
            app.InvertwindowedtransformButton = uibutton(app.UIFigure, 'push');
            app.InvertwindowedtransformButton.ButtonPushedFcn = createCallbackFcn(app, @InvertwindowedtransformButtonPushed, true);
            app.InvertwindowedtransformButton.Text = 'Invert windowed transform';
            app.OnequartermaxwindowButton = uibutton(app.UIFigure, 'push');
            app.OnequartermaxwindowButton.ButtonPushedFcn = createCallbackFcn(app, @OnequartermaxwindowButtonPushed, true);
            app.OnequartermaxwindowButton.Text = 'One-quarter max window';
            app.PreviousdatafileButton = uibutton(app.UIFigure, 'push');
            app.PreviousdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousdatafileButtonPushed, true);
            app.PreviousdatafileButton.Text = 'Previous data file';
            app.CalculateStokesParametersButton = uibutton(app.UIFigure, 'push');
            app.CalculateStokesParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateStokesParametersButtonPushed, true);
            app.CalculateStokesParametersButton.Text = 'Calculate Stokes Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gravity_wave_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end